classdef BioSNICAR_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        RUNButton             matlab.ui.control.Button
        Algae2ppbSliderLabel  matlab.ui.control.Label
        Algae2ppbSlider       matlab.ui.control.Slider
        BC1ppbSliderLabel     matlab.ui.control.Label
        BC1ppbSlider          matlab.ui.control.Slider
        Dust1ppbSliderLabel   matlab.ui.control.Label
        Dust1ppbSlider        matlab.ui.control.Slider
        Algae1ppbSliderLabel  matlab.ui.control.Label
        Algae1ppbSlider       matlab.ui.control.Slider
        Algae3ppbSliderLabel  matlab.ui.control.Label
        Algae3ppbSlider       matlab.ui.control.Slider
        Algae4ppbSliderLabel  matlab.ui.control.Label
        Algae4ppbSlider       matlab.ui.control.Slider
        Algae5ppbSliderLabel  matlab.ui.control.Label
        Algae5ppbSlider       matlab.ui.control.Slider
        Algae6ppbSliderLabel  matlab.ui.control.Label
        Algae6ppbSlider       matlab.ui.control.Slider
        BC2ppbSliderLabel     matlab.ui.control.Label
        BC2ppbSlider          matlab.ui.control.Slider
        Dust2ppbSliderLabel   matlab.ui.control.Label
        Dust2ppbSlider        matlab.ui.control.Slider
        Dust3ppbSliderLabel   matlab.ui.control.Label
        Dust3ppbSlider        matlab.ui.control.Slider
        Dust4ppbSliderLabel   matlab.ui.control.Label
        Dust4ppbSlider        matlab.ui.control.Slider
    end

    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            % FOR WEB DEPLOYMENT INSTRUCTIONS: https://uk.mathworks.com/solutions/desktop-web-deployment.html
            
            % VALUES FROM SLIDER CALLBACKS
            BIO1 = app.Algae1ppbSlider.Value;
            BIO2 = app.Algae2ppbSlider.Value;
            BIO3 = app.Algae3ppbSlider.Value;
            BIO4 = app.Algae4ppbSlider.Value;
            BIO5 = app.Algae5ppbSlider.Value;
            BIO6 = app.Algae6ppbSlider.Value;
            
            BC1 = app.BC1ppbSlider.Value;
            BC2 = app.BC2ppbSlider.Value;
            DUST1 = app.Dust1ppbSlider.Value;
            DUST2 = app.Dust2ppbSlider.Value;
            DUST3 = app.Dust3ppbSlider.Value;
            DUST4 = app.Dust4ppbSlider.Value;
            
            % CALL TO SNICAR MODEL WITH VALUES FROM SLIDERS
            [alb_slr,albedo_spectral] = SNICAR_Function_APP(1,1,1,1,0.5,0.15, ...
            [0.01,0.05,0.05,0.05,0.05], [500,500,500,500,500], [500,500,500,500,500],BC1,BC2,DUST1,DUST2,DUST3,DUST4,0,BIO1,BIO2,BIO3,BIO4,BIO5,BIO6,0,0,0);
            wvl = 0.305:0.01:5;
            
            % PLOT
            handles.alb_slr = alb_slr;
            handles.albedo_spectral = albedo_spectral;
            handles.wvl = wvl;
            app.UIAxes.XLim = [0.305 5]
            app.UIAxes.YLim = [0 1]
            plot(app.UIAxes,handles.wvl,handles.albedo_spectral)
            
        end

        % Value changed function: Algae2ppbSlider
        function Algae2ppbSliderValueChanged(app, event)
            
           BIO2 = app.Algae2ppbSlider.Value;
            
        end

        % Callback function
        function AlgaeppbSliderValueChanging(app, event)
         
        end

        % Value changed function: BC1ppbSlider
        function BC1ppbSliderValueChanged(app, event)
            
            BC1 = app.BC1ppbSlider.Value;
            
        end

        % Value changed function: Dust1ppbSlider
        function Dust1ppbSliderValueChanged(app, event)
            
            DST1 = app.Dust1ppbSlider.Value;
            
        end

        % Value changed function: Algae1ppbSlider
        function Algae1ppbSliderValueChanged(app, event)
            
            BIO1 = app.Algae1ppbSlider.Value;
            
        end

        % Value changed function: Algae3ppbSlider
        function Algae3ppbSliderValueChanged(app, event)
            
            BIO3 = app.Algae3ppbSlider.Value;
            
        end

        % Value changed function: Algae4ppbSlider
        function Algae4ppbSliderValueChanged(app, event)
            
            BIO4 = app.Algae4ppbSlider.Value;
            
        end

        % Value changed function: Algae5ppbSlider
        function Algae5ppbSliderValueChanged(app, event)
            
            BIO5 = app.Algae5ppbSlider.Value;
            
        end

        % Value changed function: Algae6ppbSlider
        function Algae6ppbSliderValueChanged(app, event)
            
            BIO6 = app.Algae6ppbSlider.Value;
            
        end

        % Value changed function: BC2ppbSlider
        function BC2ppbSliderValueChanged(app, event)
            
            BC2 = app.BC2ppbSlider.Value;
            
        end

        % Value changed function: Dust2ppbSlider
        function Dust2ppbSliderValueChanged(app, event)
            
            DST2 = app.Dust2ppbSlider.Value;
            
        end

        % Value changed function: Dust3ppbSlider
        function Dust3ppbSliderValueChanged(app, event)
           
            DST3 = app.Dust3ppbSlider.Value;
            
        end

        % Value changed function: Dust4ppbSlider
        function Dust4ppbSliderValueChanged(app, event)
            
            DST4 = app.Dust4ppbSlider.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Snow/Ice Spectral Albedo')
            xlabel(app.UIAxes, 'Wavelength')
            ylabel(app.UIAxes, 'Albedo')
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Algae2ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae2ppbSliderLabel.Text = 'Algae 2 (ppb)';
            app.Algae2ppbSlider = uislider(app.UIFigure);
            app.Algae2ppbSlider.Limits = [0 1000000];
            app.Algae2ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae2ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae2ppbSliderValueChanged, true);
            app.BC1ppbSliderLabel = uilabel(app.UIFigure);
            app.BC1ppbSliderLabel.Text = 'BC1 (ppb) ';
            app.BC1ppbSlider = uislider(app.UIFigure);
            app.BC1ppbSlider.Limits = [0 30000];
            app.BC1ppbSlider.ValueChangedFcn = createCallbackFcn(app, @BC1ppbSliderValueChanged, true);
            app.Dust1ppbSliderLabel = uilabel(app.UIFigure);
            app.Dust1ppbSliderLabel.Text = 'Dust 1 (ppb)';
            app.Dust1ppbSlider = uislider(app.UIFigure);
            app.Dust1ppbSlider.Limits = [0 1000000];
            app.Dust1ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Dust1ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Dust1ppbSliderValueChanged, true);
            app.Algae1ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae1ppbSliderLabel.Text = 'Algae 1 (ppb)';
            app.Algae1ppbSlider = uislider(app.UIFigure);
            app.Algae1ppbSlider.Limits = [0 1000000];
            app.Algae1ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae1ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae1ppbSliderValueChanged, true);
            app.Algae3ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae3ppbSliderLabel.Text = 'Algae 3 (ppb)';
            app.Algae3ppbSlider = uislider(app.UIFigure);
            app.Algae3ppbSlider.Limits = [0 1000000];
            app.Algae3ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae3ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae3ppbSliderValueChanged, true);
            app.Algae4ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae4ppbSliderLabel.Text = 'Algae 4 (ppb)';
            app.Algae4ppbSlider = uislider(app.UIFigure);
            app.Algae4ppbSlider.Limits = [0 1000000];
            app.Algae4ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae4ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae4ppbSliderValueChanged, true);
            app.Algae5ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae5ppbSliderLabel.Text = 'Algae 5 (ppb)';
            app.Algae5ppbSlider = uislider(app.UIFigure);
            app.Algae5ppbSlider.Limits = [0 1000000];
            app.Algae5ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae5ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae5ppbSliderValueChanged, true);
            app.Algae6ppbSliderLabel = uilabel(app.UIFigure);
            app.Algae6ppbSliderLabel.Text = 'Algae 6 (ppb)';
            app.Algae6ppbSlider = uislider(app.UIFigure);
            app.Algae6ppbSlider.Limits = [0 1000000];
            app.Algae6ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Algae6ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Algae6ppbSliderValueChanged, true);
            app.BC2ppbSliderLabel = uilabel(app.UIFigure);
            app.BC2ppbSliderLabel.Text = 'BC2 (ppb) ';
            app.BC2ppbSlider = uislider(app.UIFigure);
            app.BC2ppbSlider.Limits = [0 30000];
            app.BC2ppbSlider.ValueChangedFcn = createCallbackFcn(app, @BC2ppbSliderValueChanged, true);
            app.Dust2ppbSliderLabel = uilabel(app.UIFigure);
            app.Dust2ppbSliderLabel.Text = 'Dust 2 (ppb)';
            app.Dust2ppbSlider = uislider(app.UIFigure);
            app.Dust2ppbSlider.Limits = [0 1000000];
            app.Dust2ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Dust2ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Dust2ppbSliderValueChanged, true);
            app.Dust3ppbSliderLabel = uilabel(app.UIFigure);
            app.Dust3ppbSliderLabel.Text = 'Dust 3 (ppb)';
            app.Dust3ppbSlider = uislider(app.UIFigure);
            app.Dust3ppbSlider.Limits = [0 1000000];
            app.Dust3ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Dust3ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Dust3ppbSliderValueChanged, true);
            app.Dust4ppbSliderLabel = uilabel(app.UIFigure);
            app.Dust4ppbSliderLabel.Text = 'Dust 4 (ppb)';
            app.Dust4ppbSlider = uislider(app.UIFigure);
            app.Dust4ppbSlider.Limits = [0 1000000];
            app.Dust4ppbSlider.MajorTicks = [0 250000 500000 750000 1000000];
            app.Dust4ppbSlider.ValueChangedFcn = createCallbackFcn(app, @Dust4ppbSliderValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BioSNICAR_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end